classdef QNMorpho < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SingleinputTab                  matlab.ui.container.Tab
        ShowskeletonButton              matlab.ui.control.Button
        SaveBinaryButton                matlab.ui.control.Button
        FlipImageButtonGroup            matlab.ui.container.ButtonGroup
        UpDownButton                    matlab.ui.control.ToggleButton
        LeftRightButton                 matlab.ui.control.ToggleButton
        NoneButton                      matlab.ui.control.ToggleButton
        IncludeTopologicalPropertiesCheckBox  matlab.ui.control.CheckBox
        EditImageButton                 matlab.ui.control.Button
        ExportAnalysiscsvButton         matlab.ui.control.Button
        IncludeFinePropertiesCheckBox   matlab.ui.control.CheckBox
        RotationangledegreeEditField    matlab.ui.control.NumericEditField
        RotationangledegreeEditFieldLabel  matlab.ui.control.Label
        RotateImageCheckBox             matlab.ui.control.CheckBox
        FilterSizeEditField             matlab.ui.control.NumericEditField
        FilterSizeEditFieldLabel        matlab.ui.control.Label
        SaveAnalysismatButton           matlab.ui.control.Button
        AnalyzeButton                   matlab.ui.control.Button
        FilteringmethodDropDown         matlab.ui.control.DropDown
        FilteringmethodDropDownLabel    matlab.ui.control.Label
        PixelSizeinumEditField          matlab.ui.control.EditField
        PixelSizeinumEditFieldLabel     matlab.ui.control.Label
        NumberofFramesEditField         matlab.ui.control.EditField
        NumberofFramesEditFieldLabel    matlab.ui.control.Label
        ImageDir                        matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        BatchProcessingTab              matlab.ui.container.Tab
        IncludeTolologicalPropertiesCheckBox  matlab.ui.control.CheckBox
        ExportcsvfileButton             matlab.ui.control.Button
        SaveAnalysisButton_2            matlab.ui.control.Button
        NumberOfCoresEditField          matlab.ui.control.NumericEditField
        NumberOfCoresEditFieldLabel     matlab.ui.control.Label
        PoolnameEditField               matlab.ui.control.EditField
        PoolnameEditFieldLabel          matlab.ui.control.Label
        RunparallelCheckBox             matlab.ui.control.CheckBox
        AnalyzeButton_2                 matlab.ui.control.Button
        IncludeFinePropertiesCheckBox_2  matlab.ui.control.CheckBox
        FilteringmethodDropDown_2       matlab.ui.control.DropDown
        FilteringmethodDropDown_2Label  matlab.ui.control.Label
        FilterSizeEditField_2           matlab.ui.control.NumericEditField
        FilterSizeEditField_2Label      matlab.ui.control.Label
        ImageDir_2                      matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Filename;
        pixelsize=0.1; % Description
        WindowType='Average';
        WindowSize=31;
        RotAngle=0.0;
        Bulk=1;
        Fine=0;
        Topology=0;
        NeuronPosition=1;
        Filenames;
        pathname;
        Parallel=0;
        PoolName='local';
        NumCore=2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImageDir
        function ImageDirButtonPushed(app, event)
            global ImFile info n_frame PixelSize NFile filename pathname;
            
            if exist('pathname')
                [filename,pathname]=uigetfile({strcat(pathname,'*.*')},"Open File");
            else
                [filename,pathname]=uigetfile({strcat(pwd,'*.*')},"Open File");
            end
            fullpath=strcat(pathname,filename);
            ImFile=imread(fullpath);
            info=imfinfo(fullpath);
            n_frame=numel(info);
            NFile=1;
            imshow(imadjust(ImFile),'Parent',app.UIAxes);
            app.NumberofFramesEditField.Value=num2str(n_frame);
            
            if isfield(info(1),'XResolution')%%%% there is resolution filed
                if ~isempty(info(1).XResolution) && info(1).XResolution~=72 %%% 72 represents screen res not right
                    PixelSize=1/info(1).XResolution(1);
                    app.PixelSizeinumEditField.Value=strcat(num2str(PixelSize));
                else
                    app.PixelSizeinumEditField.Value='Not Found';
                end
            else
                app.PixelSizeinumEditField.Value='Not Found';
            end
        end

        % Button pushed function: SaveAnalysismatButton
        function SaveAnalysismatButtonPushed(app, event)
            global outfile;
            uisave('outfile')
        end

        % Drop down opening function: FilteringmethodDropDown
        function FilteringmethodDropDownOpening(app, event)
            app.FilteringmethodDropDown.Value='Average';
            global ImFile BW;
            BW=make_binary(ImFile,app.WindowSize,app.FilteringmethodDropDown.Value);
            imshow(BW,'Parent',app.UIAxes2)
            linkaxes([app.UIAxes,app.UIAxes2],'xy');
        end

        % Value changed function: FilteringmethodDropDown
        function FilteringmethodDropDownValueChanged(app, event)
            global ImFile BW;
            value = app.FilteringmethodDropDown.Value;
            app.WindowType=value;
            BW=make_binary(ImFile,app.WindowSize,app.WindowType);
            imshow(BW,'Parent',app.UIAxes2)
            linkaxes([app.UIAxes,app.UIAxes2],'xy');
        end

        % Value changed function: FilterSizeEditField
        function FilterSizeEditFieldValueChanged(app, event)
            value = app.FilterSizeEditField.Value;
            app.WindowSize=value;
            app.FilteringmethodDropDown.Value='Average';
            global ImFile BW;
            BW=make_binary(ImFile,app.WindowSize,app.FilteringmethodDropDown.Value);
            imshow(BW,'Parent',app.UIAxes2)
            linkaxes([app.UIAxes,app.UIAxes2],'xy');
        end

        % Value changed function: RotateImageCheckBox
        function RotateImageCheckBoxValueChanged(app, event)
            value = app.RotateImageCheckBox.Value;
            if value==0
                app.RotationangledegreeEditFieldLabel.Visible='off';
                app.RotationangledegreeEditField.Visible='off';
            else
                app.RotationangledegreeEditFieldLabel.Visible='on';
                app.RotationangledegreeEditField.Visible='on';
            end
            
        end

        % Value changed function: RotationangledegreeEditField
        function RotationangledegreeEditFieldValueChanged(app, event)
            global ImFile;
            app.RotAngle=pi*app.RotationangledegreeEditField.Value/180;
            [ImFile,xr]=Rotate_Image(ImFile,app.RotAngle,[0,0;0,0]);
            imshow(imadjust(ImFile),'Parent',app.UIAxes);
        end

        % Value changed function: IncludeFinePropertiesCheckBox
        function IncludeFinePropertiesCheckBoxValueChanged(app, event)
            value= app.IncludeFinePropertiesCheckBox.Value ;
            if value ==0
                app.Fine=0;
            else
                app.Fine=1;
            end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            global ImFile info n_frame PixelSize BW outfile filename pathname;
            params.FileName=filename;
            params.FilePath=pathname;
            params.pixelsize=PixelSize;
            params.persislen_threshold=10.0/PixelSize;
            params.Fine=app.Fine;
            params.Topology=app.Topology;
            outfile=Scan_Video(BW,params,info);

        end

        % Button pushed function: ImageDir_2
        function ImageDir_2ButtonPushed(app, event)
            global files paths NFile;
            
            if exist('paths')
                [files,paths]=uigetfile({strcat(paths,'*.*')},'All Files (*.*)','MultiSelect','on');
            else
                [files,paths]=uigetfile({strcat(pwd,'*.*')},'All Files (*.*)','MultiSelect','on');
            end
%             [files,paths]=uigetfile('*.*','All Files (*.*)','MultiSelect','on');
            NFile=length(files);
        end

        % Value changed function: FilterSizeEditField_2
        function FilterSizeEditField_2ValueChanged(app, event)
            value = app.FilterSizeEditField_2.Value;
            app.WindowSize=value;
        end

        % Drop down opening function: FilteringmethodDropDown_2
        function FilteringmethodDropDown_2Opening(app, event)
            app.FilteringmethodDropDown_2.Value='Average';
        end

        % Value changed function: FilteringmethodDropDown_2
        function FilteringmethodDropDown_2ValueChanged(app, event)
            value = app.FilteringmethodDropDown_2.Value;
            app.WindowType=value;
        end

        % Value changed function: IncludeFinePropertiesCheckBox_2
        function IncludeFinePropertiesCheckBox_2ValueChanged(app, event)
            value = app.IncludeFinePropertiesCheckBox_2.Value;
            app.Fine=value;
        end

        % Value changed function: RunparallelCheckBox
        function RunparallelCheckBoxValueChanged(app, event)
            value = app.RunparallelCheckBox.Value;
            app.Parallel=value;
            if value==0
                app.PoolnameEditFieldLabel.Visible='off';
                app.PoolnameEditField.Visible='off';
                app.NumberOfCoresEditFieldLabel.Visible='off';
                app.NumberOfCoresEditField.Visible='off';
            else
                app.PoolnameEditFieldLabel.Visible='on';
                app.PoolnameEditField.Visible='on';
                app.NumberOfCoresEditFieldLabel.Visible='on';
                app.NumberOfCoresEditField.Visible='on';
            end
        end

        % Value changed function: PoolnameEditField
        function PoolnameEditFieldValueChanged(app, event)
            value = app.PoolnameEditField.Value;
            app.PoolName=value;
            
        end

        % Value changed function: NumberOfCoresEditField
        function NumberOfCoresEditFieldValueChanged(app, event)
            value = app.NumberOfCoresEditField.Value;
            app.NumCore=value;
        end

        % Button pushed function: AnalyzeButton_2
        function AnalyzeButton_2Pushed(app, event)
            global outfile files paths NFile;
            params.pixelsize=app.pixelsize;
            params.persislen_threshold=10/app.pixelsize;
            params.Fine=app.Fine;
            params.Parallel=app.Parallel;
            params.NFile=NFile;
            params.PoolName=app.PoolName;
            params.Topology=app.Topology;
            params.NumCore=app.NumCore;
            params.WindowSize=app.WindowSize;
            params.WindowType=app.WindowType;
            outfile=Run_Scan(files,paths,params);
        end

        % Button pushed function: SaveAnalysisButton_2
        function SaveAnalysisButton_2Pushed(app, event)
             global outfile filename; 
            uisave('outfile',filename) ;

        end

        % Button pushed function: ExportAnalysiscsvButton
        function ExportAnalysiscsvButtonPushed(app, event)
            global outfile filename pathname savepath;
            [~,name,ext] = fileparts(filename);
            if exist('savepath')
            [filename, savepath] = uiputfile(strcat(savepath,name,'.csv'), 'Choose a file name');
            else
            [filename, savepath] = uiputfile(strcat(pathname,name,'.csv'), 'Choose a file name'); 
            end
            outname = fullfile(savepath, filename);
            Master_Table=export_csvFile(outfile);
            writetable(Master_Table,outname)
        end

        % Button pushed function: ExportcsvfileButton
        function ExportcsvfileButtonPushed(app, event)
            global outfile;

            [filesave, savepath] = uiputfile('Analysis.csv');    
            outname = fullfile(savepath, filesave);
            Master_Table=export_csvFile(outfile);
            writetable(Master_Table,outname)
        end

        % Button pushed function: EditImageButton
        function EditImageButtonPushed(app, event)
            global BW filename;
            ss=ROI_Editor(BW,filename);
            waitfor(ss.UIFigure);
            BW=logical(ss.imf);
            imshow(BW,'Parent',app.UIAxes2)
        end

        % Value changed function: IncludeTopologicalPropertiesCheckBox
        function IncludeTopologicalPropertiesCheckBoxValueChanged(app, event)
            value= app.IncludeTopologicalPropertiesCheckBox.Value;
            if value==0
                app.Topology=0;
            else
                app.Topology=1;
            end
        end

        % Selection changed function: FlipImageButtonGroup
        function FlipImageButtonGroupSelectionChanged2(app, event)
            selectedButton = app.FlipImageButtonGroup.SelectedObject;
            global ImFile BW
            ImOr=ImFile;
            BWOr=BW;
            if strcmp(selectedButton.Text,'LeftRight')==1     
                ImFile=fliplr(ImOr);
                BW=fliplr(BWOr);
                imshow(imadjust(ImFile),'Parent',app.UIAxes);
                imshow(BW,'Parent',app.UIAxes2);
                linkaxes([app.UIAxes,app.UIAxes2],'xy');
            end
            if strcmp(selectedButton.Text,'UpDown')==1
                ImFile=flipud(ImOr);
                BW=flipud(BWOr);
                imshow(imadjust(ImFile),'Parent',app.UIAxes);
                imshow(BW,'Parent',app.UIAxes2);
                linkaxes([app.UIAxes,app.UIAxes2],'xy');
            end
              if strcmp(selectedButton.Text,'None')==1
                ImFile=ImOr;
                BW=BWOr;
                imshow(imadjust(ImFile),'Parent',app.UIAxes);
                imshow(BW,'Parent',app.UIAxes2);
                linkaxes([app.UIAxes,app.UIAxes2],'xy');
            end
        end

        % Button pushed function: SaveBinaryButton
        function SaveBinaryButtonPushed(app, event)

                [FileName, FilePath] = uiputfile({'*.tif';'*.*'},'Save As');
                File = fullfile(FilePath, FileName);
                imwrite(BW, File, 'tif');

        end

        % Button pushed function: ShowskeletonButton
        function ShowskeletonButtonPushed(app, event)
            global BW
                soma_im=bwareafilt(imerode(BW,strel('disk',6)),1,'largest');
                soma_im=imfill(soma_im,'holes');
                soma_im=imgaussfilt(im2double(soma_im),6);
                soma_im=imbinarize(soma_im);
                soma_im=imfill(soma_im,'holes');
                BW=BW|soma_im;
            figure
            imshow(bwmorph(BW,'thin','inf'))
        end

        % Value changed function: IncludeTolologicalPropertiesCheckBox
        function IncludeTolologicalPropertiesCheckBoxValueChanged(app, event)
            value = app.IncludeTolologicalPropertiesCheckBox.Value;
            app.Topology=value;
        end

        % Value changed function: PixelSizeinumEditField
        function PixelSizeinumEditFieldValueChanged(app, event)
             global PixelSize
            value = app.PixelSizeinumEditField.Value;
            app.pixelsize=str2double(value);
            PixelSize=app.pixelsize;
        end

        % Value changing function: PixelSizeinumEditField
        function PixelSizeinumEditFieldValueChanging(app, event)
            global PixelSize
            changingValue = event.Value;
            app.pixelsize=str2double(changingValue);
            PixelSize=app.pixelsize;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleinputTab = uitab(app.TabGroup);
            app.SingleinputTab.Title = 'Single input';
            app.UIAxes = uiaxes(app.SingleinputTab);
            title(app.UIAxes, 'Adjusted Raw Image')
            app.UIAxes2 = uiaxes(app.SingleinputTab);
            title(app.UIAxes2, 'Binarized Image')
            app.ImageDir = uibutton(app.SingleinputTab, 'push');
            app.ImageDir.ButtonPushedFcn = createCallbackFcn(app, @ImageDirButtonPushed, true);
            app.ImageDir.Text = 'Load single image';
            app.NumberofFramesEditFieldLabel = uilabel(app.SingleinputTab);
            app.NumberofFramesEditFieldLabel.Text = 'Number of Frames';
            app.NumberofFramesEditField = uieditfield(app.SingleinputTab, 'text');
            app.PixelSizeinumEditFieldLabel = uilabel(app.SingleinputTab);
            app.PixelSizeinumEditFieldLabel.Text = 'Pixel Size in um';
            app.PixelSizeinumEditField = uieditfield(app.SingleinputTab, 'text');
            app.PixelSizeinumEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizeinumEditFieldValueChanged, true);
            app.PixelSizeinumEditField.ValueChangingFcn = createCallbackFcn(app, @PixelSizeinumEditFieldValueChanging, true);
            app.FilteringmethodDropDownLabel = uilabel(app.SingleinputTab);
            app.FilteringmethodDropDownLabel.Text = 'Filtering method';
            app.FilteringmethodDropDown = uidropdown(app.SingleinputTab);
            app.FilteringmethodDropDown.Items = {'Average', 'Gaussian', 'Median'};
            app.FilteringmethodDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FilteringmethodDropDownOpening, true);
            app.FilteringmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FilteringmethodDropDownValueChanged, true);
            app.FilteringmethodDropDown.Value = 'Average';
            app.AnalyzeButton = uibutton(app.SingleinputTab, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.SaveAnalysismatButton = uibutton(app.SingleinputTab, 'push');
            app.SaveAnalysismatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysismatButtonPushed, true);
            app.SaveAnalysismatButton.Text = 'Save Analysis (.mat) ';
            app.FilterSizeEditFieldLabel = uilabel(app.SingleinputTab);
            app.FilterSizeEditFieldLabel.Text = 'Filter Size';
            app.FilterSizeEditField = uieditfield(app.SingleinputTab, 'numeric');
            app.FilterSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSizeEditFieldValueChanged, true);
            app.RotateImageCheckBox = uicheckbox(app.SingleinputTab);
            app.RotateImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotateImageCheckBoxValueChanged, true);
            app.RotateImageCheckBox.Text = 'Rotate Image';
            app.RotationangledegreeEditFieldLabel = uilabel(app.SingleinputTab);
            app.RotationangledegreeEditFieldLabel.Visible = 'off';
            app.RotationangledegreeEditFieldLabel.Text = 'Rotation angle (degree)';
            app.RotationangledegreeEditField = uieditfield(app.SingleinputTab, 'numeric');
            app.RotationangledegreeEditField.ValueChangedFcn = createCallbackFcn(app, @RotationangledegreeEditFieldValueChanged, true);
            app.RotationangledegreeEditField.Visible = 'off';
            app.IncludeFinePropertiesCheckBox = uicheckbox(app.SingleinputTab);
            app.IncludeFinePropertiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeFinePropertiesCheckBoxValueChanged, true);
            app.IncludeFinePropertiesCheckBox.Text = 'Include Fine Properties';
            app.ExportAnalysiscsvButton = uibutton(app.SingleinputTab, 'push');
            app.ExportAnalysiscsvButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAnalysiscsvButtonPushed, true);
            app.ExportAnalysiscsvButton.Text = 'Export Analysis (.csv) ';
            app.EditImageButton = uibutton(app.SingleinputTab, 'push');
            app.EditImageButton.ButtonPushedFcn = createCallbackFcn(app, @EditImageButtonPushed, true);
            app.EditImageButton.Text = 'Edit Image';
            app.IncludeTopologicalPropertiesCheckBox = uicheckbox(app.SingleinputTab);
            app.IncludeTopologicalPropertiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeTopologicalPropertiesCheckBoxValueChanged, true);
            app.IncludeTopologicalPropertiesCheckBox.Text = 'Include Topological Properties';
            app.FlipImageButtonGroup = uibuttongroup(app.SingleinputTab);
            app.FlipImageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FlipImageButtonGroupSelectionChanged2, true);
            app.FlipImageButtonGroup.Title = 'Flip Image';
            app.NoneButton = uitogglebutton(app.FlipImageButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.LeftRightButton = uitogglebutton(app.FlipImageButtonGroup);
            app.LeftRightButton.Text = 'LeftRight';
            app.UpDownButton = uitogglebutton(app.FlipImageButtonGroup);
            app.UpDownButton.Text = 'UpDown';
            app.SaveBinaryButton = uibutton(app.SingleinputTab, 'push');
            app.SaveBinaryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBinaryButtonPushed, true);
            app.SaveBinaryButton.Text = 'Save Binary';
            app.ShowskeletonButton = uibutton(app.SingleinputTab, 'push');
            app.ShowskeletonButton.ButtonPushedFcn = createCallbackFcn(app, @ShowskeletonButtonPushed, true);
            app.ShowskeletonButton.Text = 'Show skeleton';
            app.BatchProcessingTab = uitab(app.TabGroup);
            app.BatchProcessingTab.Title = 'Batch Processing';
            app.ImageDir_2 = uibutton(app.BatchProcessingTab, 'push');
            app.ImageDir_2.ButtonPushedFcn = createCallbackFcn(app, @ImageDir_2ButtonPushed, true);
            app.ImageDir_2.Text = 'Load Image Files';
            app.FilterSizeEditField_2Label = uilabel(app.BatchProcessingTab);
            app.FilterSizeEditField_2Label.Text = 'Filter Size';
            app.FilterSizeEditField_2 = uieditfield(app.BatchProcessingTab, 'numeric');
            app.FilterSizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @FilterSizeEditField_2ValueChanged, true);
            app.FilteringmethodDropDown_2Label = uilabel(app.BatchProcessingTab);
            app.FilteringmethodDropDown_2Label.Text = 'Filtering method';
            app.FilteringmethodDropDown_2 = uidropdown(app.BatchProcessingTab);
            app.FilteringmethodDropDown_2.Items = {'Average', 'Gaussian', 'Median'};
            app.FilteringmethodDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @FilteringmethodDropDown_2Opening, true);
            app.FilteringmethodDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FilteringmethodDropDown_2ValueChanged, true);
            app.FilteringmethodDropDown_2.Value = 'Average';
            app.IncludeFinePropertiesCheckBox_2 = uicheckbox(app.BatchProcessingTab);
            app.IncludeFinePropertiesCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @IncludeFinePropertiesCheckBox_2ValueChanged, true);
            app.IncludeFinePropertiesCheckBox_2.Text = 'Include Fine Properties';
            app.AnalyzeButton_2 = uibutton(app.BatchProcessingTab, 'push');
            app.AnalyzeButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_2Pushed, true);
            app.AnalyzeButton_2.Text = 'Analyze';
            app.RunparallelCheckBox = uicheckbox(app.BatchProcessingTab);
            app.RunparallelCheckBox.ValueChangedFcn = createCallbackFcn(app, @RunparallelCheckBoxValueChanged, true);
            app.RunparallelCheckBox.Text = 'Run parallel';
            app.PoolnameEditFieldLabel = uilabel(app.BatchProcessingTab);
            app.PoolnameEditFieldLabel.Visible = 'off';
            app.PoolnameEditFieldLabel.Text = 'Pool name';
            app.PoolnameEditField = uieditfield(app.BatchProcessingTab, 'text');
            app.PoolnameEditField.ValueChangedFcn = createCallbackFcn(app, @PoolnameEditFieldValueChanged, true);
            app.PoolnameEditField.Visible = 'off';
            app.NumberOfCoresEditFieldLabel = uilabel(app.BatchProcessingTab);
            app.NumberOfCoresEditFieldLabel.Visible = 'off';
            app.NumberOfCoresEditFieldLabel.Text = 'Number Of Cores';
            app.NumberOfCoresEditField = uieditfield(app.BatchProcessingTab, 'numeric');
            app.NumberOfCoresEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfCoresEditFieldValueChanged, true);
            app.NumberOfCoresEditField.Visible = 'off';
            app.SaveAnalysisButton_2 = uibutton(app.BatchProcessingTab, 'push');
            app.SaveAnalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisButton_2Pushed, true);
            app.SaveAnalysisButton_2.Text = 'Save Analysis ';
            app.ExportcsvfileButton = uibutton(app.BatchProcessingTab, 'push');
            app.ExportcsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportcsvfileButtonPushed, true);
            app.ExportcsvfileButton.Text = 'Export csv file';
            app.IncludeTolologicalPropertiesCheckBox = uicheckbox(app.BatchProcessingTab);
            app.IncludeTolologicalPropertiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeTolologicalPropertiesCheckBoxValueChanged, true);
            app.IncludeTolologicalPropertiesCheckBox.Text = 'Include Tolological Properties';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QNMorpho

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end